classdef MultiSelectDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        propertypSpinnerLabel  matlab.ui.control.Label
        pSpinner               matlab.ui.control.Spinner
        genegListBoxLabel      matlab.ui.control.Label
        gListBox               matlab.ui.control.ListBox
        nucleusnListBoxLabel   matlab.ui.control.Label
        nListBox               matlab.ui.control.ListBox
        RightPanel             matlab.ui.container.Panel
        pngTable               matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        png % p value for nucleus n and gene g --- not used; data is stored in app.pngTable.Data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            nmax = numel (app.nListBox.Items);
            gmax = numel (app.gListBox.Items);
            app.pngTable.RowName    = app.nListBox.Items;
            app.pngTable.ColumnName = app.gListBox.Items;
            app.pngTable.Data       = zeros(nmax,gmax);
        end

        % Value changed function: pSpinner
        function pSpinnerValueChanged(app, event)
            pNew = app.pSpinner.Value;            
            nSelected = find (ismember (app.nListBox.Items, app.nListBox.Value));
            gSelected = find (ismember (app.gListBox.Items, app.gListBox.Value));
            app.pngTable.Data (nSelected, gSelected) = pNew;
        end

        % Value changed function: gListBox
        function gListBoxValueChanged(app, event)
            nSelected = find (ismember (app.nListBox.Items, app.nListBox.Value));
            gSelected = find (ismember (app.gListBox.Items, app.gListBox.Value));
            app.pSpinner.Limits = [-Inf +Inf];
            if (numel(nSelected)==1 && numel(gSelected)==1)
                app.pSpinner.Value = app.pngTable.Data (nSelected, gSelected) ;
            else
                app.pSpinner.Value = +Inf;
            end
        end

        % Value changed function: nListBox
        function nListBoxValueChanged(app, event)
            nSelected = find (ismember (app.nListBox.Items, app.nListBox.Value));
            gSelected = find (ismember (app.gListBox.Items, app.gListBox.Value));
            if (numel(nSelected)==1 && numel(gSelected)==1)
                app.pSpinner.Value = app.pngTable.Data (nSelected, gSelected) ;
            else
                app.pSpinner.Value = +Inf;
            end
            % Note: nListBoxValueChanged(..) could be the same funciton as
            % gListBoxValueChanged
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {336, 336};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.propertypSpinnerLabel = uilabel(app.LeftPanel);
            app.propertypSpinnerLabel.Text = 'property p';
            app.pSpinner = uispinner(app.LeftPanel);
            app.pSpinner.Step = 0.1;
            app.pSpinner.ValueChangedFcn = createCallbackFcn(app, @pSpinnerValueChanged, true);
            app.genegListBoxLabel = uilabel(app.LeftPanel);
            app.genegListBoxLabel.Text = 'gene g';
            app.gListBox = uilistbox(app.LeftPanel);
            app.gListBox.Items = {'H', 'K', 'G', 'N'};
            app.gListBox.Multiselect = 'on';
            app.gListBox.ValueChangedFcn = createCallbackFcn(app, @gListBoxValueChanged, true);
            app.gListBox.Value = {'H'};
            app.nucleusnListBoxLabel = uilabel(app.LeftPanel);
            app.nucleusnListBoxLabel.Text = 'nucleus n';
            app.nListBox = uilistbox(app.LeftPanel);
            app.nListBox.Items = {'8227', '8228'};
            app.nListBox.Multiselect = 'on';
            app.nListBox.ValueChangedFcn = createCallbackFcn(app, @nListBoxValueChanged, true);
            app.nListBox.Value = {'8227'};
            app.RightPanel = uipanel(app.GridLayout);
            app.pngTable = uitable(app.RightPanel);
            app.pngTable.ColumnName = {''};
            app.pngTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiSelectDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end