classdef figrApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIGRParameterExplorerUIFigure   matlab.ui.Figure
        xntgAxes                        matlab.ui.control.UIAxes
        GeneListBoxLabel                matlab.ui.control.Label
        GeneListBox                     matlab.ui.control.ListBox
        NucleusListBoxLabel             matlab.ui.control.Label
        NucleusListBox                  matlab.ui.control.ListBox
        SaveoptionsButton               matlab.ui.control.Button
        LoadoptionsButton               matlab.ui.control.Button
        LoadFilesButton                 matlab.ui.control.Button
        InferGRNButton                  matlab.ui.control.Button
        MouseoveraspinnertoviewitstooltipLabel  matlab.ui.control.Label
        biplotAxes                      matlab.ui.control.UIAxes
        ChooseFileFormat                matlab.ui.container.ButtonGroup
        MDA                             matlab.ui.control.RadioButton
        NCBI                            matlab.ui.control.RadioButton
        GeneexpressiondatafileTextAreaLabel  matlab.ui.control.Label
        GeneexpressiondatafileTextArea  matlab.ui.control.TextArea
        TimepointsfileTextAreaLabel     matlab.ui.control.Label
        TimepointsfileTextArea          matlab.ui.control.TextArea
        ChooseButton_expression_file    matlab.ui.control.Button
        ChooseButton_timepoint_file     matlab.ui.control.Button
        OptionsfileTextAreaLabel        matlab.ui.control.Label
        OptionsfileTextArea             matlab.ui.control.TextArea
        ChooseButton_options_file       matlab.ui.control.Button
        pSmoothingSpinnerLabel          matlab.ui.control.Label
        pSmoothingSpinner               matlab.ui.control.Spinner
        vThresholdSpinnerLabel          matlab.ui.control.Label
        vThresholdSpinner               matlab.ui.control.Spinner
        xThresholdSpinnerLabel          matlab.ui.control.Label
        xThresholdSpinner               matlab.ui.control.Spinner
        tSafetySpinnerLabel             matlab.ui.control.Label
        tSafetySpinner                  matlab.ui.control.Spinner
        CopypSmoothing                  matlab.ui.control.CheckBox
        CopyvThreshold                  matlab.ui.control.CheckBox
        CopyxThreshold                  matlab.ui.control.CheckBox
        CopytSafety                     matlab.ui.control.CheckBox
        CopytoallothergenesCheckBox     matlab.ui.control.CheckBox
        CopytoallnucleiconditionsCheckBox  matlab.ui.control.CheckBox
        CopyButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        xntg                 % gene expression data
        tt
        yntg                % output by infer(); also generated by gui?
        nucleusNames
        geneNames
        pvxArr
        pvxUser
        optionsFileLoaded = 0; % Flag for loaded file with p, v, and x opts
        userChangedOpt       % Flags for wheather user has changed the opts using the gui
        geneMap = containers.Map
        nucleusMap = containers.Map
        grn = []             % grn (set after first time "infer" button is pushed)
        xntgRECAL = []       % recalculated trajs (set after first time "infer" button is pushed)
    end
    
    methods (Access = private)
        
        %==========================================================================================
        %  DRAW THE GENE EXPRESSION TRAJECTORY FOR THE SELECTED GENE IN THE SELECTED NUCLEUS (OR CONDITION)
        %==========================================================================================
        function redrawXNTGAxes (app)
            
            tt = app.tt;
            
            gNames = string(app.GeneListBox.Value);                 % selected gene names
            [dum,gIndices] = intersect (app.geneNames, gNames);     % selected gene indices
            nNames = string(app.NucleusListBox.Value);              % selected nucleus names
            [dum,nIndices] = intersect (app.nucleusNames, nNames);  % selected nucleus indices
                        
            %Another way might be:   nIndices = find (ismember (app.nucleusNames, nNames));
            
            % plot trajs
            cla (app.xntgAxes);
            hold (app.xntgAxes, 'on');
            tpltmin = min(tt);
            tpltmax = max(tt);
            tplt = linspace (tpltmin, tpltmax, 50);
            tplt = unique ([tplt'  ; tt]);
           
            vt = [];
            
            p = app.pSmoothingSpinner.Value;                                
            vThreshold = app.vThresholdSpinner.Value;
            xThreshold = app.xThresholdSpinner.Value;
            tsafety = app.tSafetySpinner.Value;
            
            %-----------------------------------------------
            % Visualize the thresholds
            %-----------------------------------------------
            plot (app.xntgAxes, ...
                [tpltmin tpltmax], [xThreshold xThreshold], 'k--', ...
                [tpltmin tpltmax], [xThreshold xThreshold+vThreshold*(tpltmax-tpltmin)], 'k--', ...
                [tpltmin tpltmax], [xThreshold xThreshold-vThreshold*(tpltmax-tpltmin)], 'k--');
                               
            %-----------------------------------------------
            % Loop over all selected genes and nuclei
            %-----------------------------------------------
            nColorsNeeded = length(nIndices);
            colorOrder = get (app.xntgAxes, 'colororder');         
            colorOrder = repmat (colorOrder, [ceil(nColorsNeeded/numel(colorOrder)) 1]) 
            %  YLL 2020-6-5   Pad cyclically (Wastes a couple of slots)
            
            for k=1:length(gIndices) 
                for j=1:length(nIndices)
                    n = nIndices(j);
                    g = gIndices(k);
                    xSplineFN = csaps (tt, app.xntg(n,:,g), p);
                    xSpline = fnval (xSplineFN,tplt);
                    vSplineFN = fnder (xSplineFN);         % Calculate derivative w.r.t. gene g!
                    for t = 1:length(tt)                   % At each timepoint
                        vt(t) = fnval (vSplineFN, tt(t));  % Evaluate derivative
                        if (abs(vt(t)) > vThreshold)	 % If derivative is significant
                            yt(t) = sign (vt(t));      % Infer on/off state from sign of derivative
                        else							% Else infer on/off state from size of x itself
                            yt(t) = sign (app.xntg(n,t,g) - xThreshold);
                        end           					% YLL 2018-10-2
                    end   
                    
                    %======= Check tsafety.                      
                    tmax = length(tt);
                    ytFilt = yt;                   
                    for t=1+tsafety:tmax-tsafety
                        % FILTER OUT DATAPOINTS WHOSE SLOPES ARE
                        % NOT PART OF A RUN OF tSafety OR MORE OF THE SAME
                        for t2=t-tsafety : t+tsafety
                            if (yt(t2) ~= yt(t))
                                ytFilt(t) = 0.5;
                            end
                        end
                    end % for t
                    
                    
                    yt = ytFilt;
                    %======= Plot ON points as filled circles, OFF points as empty
                    %circles, DROPPED points as crosses
                    tSel = (yt>=1);
                    plot (app.xntgAxes, app.tt(tSel), app.xntg (n,tSel,g), 'o', 'MarkerFaceColor', colorOrder(j,:), 'color', colorOrder(j,:));
                    tSel = (yt<=0);
                    plot (app.xntgAxes, app.tt(tSel), app.xntg (n,tSel,g), 'o', 'color', colorOrder(j,:));
                    tSel = (yt==0.5);
                    plot (app.xntgAxes, app.tt(tSel), app.xntg (n,tSel,g), 'x', 'color', colorOrder(j,:));
                    %======= Plot spline
                    plot (app.xntgAxes, tplt, xSpline, '-', 'color', colorOrder(j,:));
                    
                    
                    %======= YLL 2020-6-5: Plot xntgRECAL if it exists
                    if (numel(app.xntgRECAL) > 0)
                        disp ('plotting recal!');
                        plot (app.xntgAxes, app.tt, app.xntgRECAL(n,:,g), 'x--', 'color', colorOrder(j,:));                    
                    end
                    
                end
            end
            app.xntgAxes.YLim = [ min(0,min(app.xntg(:)))   max(app.xntg(:))];    %YLL 2020-6-1: resize vertical axis to fit all x(n,t,g) data
        end
        
        %===========================================================================
        % DRAW THE BIPLOT
        %===========================================================================
        function results = redrawBiplotAxes(app, g1, g)
            % NEED TO ADD!!!
        end
        
        function populateListBoxDropDown(app)
            %==========================================================================================
            %  POPULATE THE ListBox and DropDown WITH ITEMS FOR THE USER TO
            %  SELECT
            %==========================================================================================
            app.GeneListBox.Items = app.geneNames;
            app.NucleusListBox.Items = app.nucleusNames;
            
            %app.pSmoothingSlider.MajorTicks = linspace (0, 1, 11);
            %app.vThresholdSlider.MajorTicks = linspace (0, 0.02, 11);
            %app.xThresholdSlider.MajorTicks = linspace (0, 1, 11);
            
            [numNuclei] = numel(app.nucleusNames);
            [numGenes] = numel(app.geneNames);

            % Store values of options p, v, and x (and tSafety???) for each gene and nucleus [DEFAULT UNIVERSAL VALUES].
            % PLEASE ADHERE TO THIS INDEX ORDER!  n, g, o
            app.pvxArr(1:numNuclei, 1:numGenes, 1) = app.pSmoothingSpinner.Value;
            app.pvxArr(1:numNuclei, 1:numGenes, 2) = app.vThresholdSpinner.Value;
            app.pvxArr(1:numNuclei, 1:numGenes, 3) = app.xThresholdSpinner.Value;
            
            % Manu 07/20/2020: Initializing to sensible values based on
            % gene expression data instead of uniform values above
            initializePvrArr(app);
            
            % YLL 2020-6-5: Eventually, both the "containers.Map" approach
            % and the "intersect" or "find(ismember())" approach could be
            % replaced by the ItemsList approach, but I am not ready to try
            % this yet
            
            % Mapper for gene - number (in array)
            app.geneMap = containers.Map(app.geneNames, [1:numGenes]);           
            % Mapper for nucleus - number (in array)
            app.nucleusMap = containers.Map(app.nucleusNames, [1:numNuclei]);
       
            % Update current slider values.
            app.GeneListBox.Value = app.GeneListBox.Items{1};
            app.NucleusListBox.Value = app.NucleusListBox.Items{1};
            
            app.pSmoothingSpinner.Value = app.pvxArr(1,1,1);
            app.vThresholdSpinner.Value = app.pvxArr(1,1,2);
            app.xThresholdSpinner.Value = app.pvxArr(1,1,3);
            
            redrawXNTGAxes (app);
        end
        
        function loadMDADataTimepoints(app, expr_filename, timepoints_filename)
            
            %==========================================================================================
            %  READ THE EXPRESSION DATA FILE!
            %==========================================================================================
            disp(expr_filename);
            app.xntg = loadMDA (expr_filename);
            
            [nmax, tmax, gmax] = size (app.xntg);
            app.geneNames = string(cellstr(transpose(char( 64+(1:gmax) ))));     % default gene names (letters)
            app.nucleusNames = string(1 : nmax)';  % default nucleus names (integers)
            app.tt = (1 : tmax)';                   % default timepoints (integers)
          
            
            %==========================================================================================
            %  READ THE TIMEPOINTS FILE!
            %==========================================================================================
            app.tt = loadMDA (timepoints_filename);
            tmax = numel (app.tt);
            
            populateListBoxDropDown(app);
    
            %==========================================================================================
            %  Manu 07/13/2020: Unclear whether these are used.
            %==========================================================================================
            % grn = [];             % CLEAR THESE!    
            % xntgRECAL = [];
               
        end
        
        % Load the expression data and timepoint data in NCBI format and
        % store them as class properties
        function loadNCBIDataTimepoints(app, expr_filename, timepoints_filename)
            
            %==========================================================================================
            %  READ THE EXPRESSION DATA FILE!
            %==========================================================================================
            %==========================================================================================
            %  READ THE DATA FILES!
            %==========================================================================================
            [app.xntg, app.tt, app.nucleusNames, app.geneNames] = ...
                loadNCBIFiles (expr_filename, timepoints_filename); % see readGeneExprFiles.m
            
            % Populate the list boxes and the drop down choosers
            populateListBoxDropDown(app);
               
        end
        
        % Initialize parameters to sensible values based on the expression
        % data
        function initializePvrArr(app)
            
            % pSmoothing - initialize to 0.5 (not a lot of smoothing)
            % Manu 07/20/2020: TTD: Set initial pSmoothing value based on 
            % the amount of noise in the data.
            app.pvxArr(:,:,1) = 0.01;
            
            % vThreshold - estimate the avergae slope and set the velocity
            % threshold to 1/10th its value.
            numGenes = numel (app.geneNames);
            numNuclei = numel (app.nucleusNames);
            for g = 1:numGenes
                    xstart = app.xntg(:, 1, g);
                    xend = app.xntg(:, end, g);
                    tchange = app.tt(end) - app.tt(1);
                    averageRateOfChange = abs(xstart-xend)/tchange;
                    app.pvxArr(:, g, 2) = max(averageRateOfChange)/5;
            end
            
            % xThreshold - set to the average initial value
            for g = 1:numGenes
                app.pvxArr(:, g, 3) = 1.5*mean(app.xntg(:, 1, g));
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %==========================================================================================
            %  CLEAR COMMAND WINDOW (IN PREPARATION FOR USING IT TO OUTPUT
            %  GUI DEBUGGING INFO)
            %==========================================================================================
            clc;
            
            %==========================================================================================
            %  CLEAR COMMAND WINDOW (IN PREPARATION FOR USING IT TO OUTPUT
            %  GUI DEBUGGING INFO)
            %==========================================================================================
            app.GeneexpressiondatafileTextArea.Value = 'File name...';
            app.TimepointsfileTextArea.Value = 'File name...';
            app.OptionsfileTextArea.Value = 'File name...';
                        
        end

        % Value changed function: GeneListBox
        function GeneListBoxValueChanged(app, event)
            
            
            value = cellstr(app.GeneListBox.Value);
            g = app.geneMap(string(value));  
            gList = values (app.geneMap, value); % YLL 2020-6-1
            gList = cell2mat(gList);     % convert strings to integers (gene indices)
       
            % Manu 07/20/2020: TTD: Remove this if statement. It is
            % redundant since we only allow one gene to be selected at a
            % time.
            if (numel(gList)==1)
                
                %======== User has selected EXACTLY one gene.  
                % Run Joanna's code to set slider positions.
                g = gList;
            
                % Update inserted by the user options
                % YLL 2020-6-5: problems 
                for i=1:numel(string(app.NucleusListBox.Value))
                    app.pSmoothingSpinner.Value = app.pvxArr( ...
                        app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                        app.geneMap(string(app.GeneListBox.Value)), ...
                        1);
                    app.vThresholdSpinner.Value = app.pvxArr( ...
                        app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                        app.geneMap(string(app.GeneListBox.Value)), ...
                        2);
                    app.xThresholdSpinner.Value = app.pvxArr( ...
                        app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                        app.geneMap(string(app.GeneListBox.Value)), ...
                        3);
                end
                
                % Manu 07/20/2020: Why does it matter whether the options
                % file has been loaded or not? Why does it matter whether
                % the user has changed options or not? We should just
                % change to spinners/sliders to the current value of the
                % parameters, which might have gotten there because the
                % user loaded a file or because they changed them. Also
                % this is not being done in NucleusListBoxValueChanged()/
                % Commenting out.
                % TTD: Remove if no major problems discovered.
%                 if(app.optionsFileLoaded)
%                     for n=1:numel(string(app.NucleusListBox.Value))
%                         if(app.userChangedOpt(n,g,1) == 0)
%                             app.pSmoothingSpinner.Value = app.pvxUser(n,g,1);
%                         end
%                         if(app.userChangedOpt(n,g,1) == 0)
%                             app.vThresholdSpinner.Value = app.pvxUser(n,g,2);
%                         end
%                         if(app.userChangedOpt(n,g,1) == 0)
%                             app.xThresholdSpinner.Value = app.pvxUser(n,g,3);
%                         end
%                     end
%                 end
                
            end
 
            fprintf ("APP: Selected gene(s) %s\n", string(value));           
            redrawXNTGAxes (app);

        end

        % Value changed function: NucleusListBox
        function NucleusListBoxValueChanged(app, event)
            

            value = cellstr(app.NucleusListBox.Value);
            nList = values (app.nucleusMap, value); % YLL 2020-6-1
            nList = cell2mat(nList);     % convert nucleus-name strings to integers (gene indices)
            
            % Update inserted by the user parameters
            
            %  YLL 2020-6-1:  Joanna, I think this is not correct.  I am
            %  getting errors.  I had to comment it out
            % Manu 07/17/2020: The problem was that the n and g indices
            % for app.pvxArr were reversed. I have just copied Yen Lee's
            % code from GeneListBoxValueChanged() below.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pSmoothingSpinner.Value = app.pvxArr( ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                    app.geneMap(string(app.GeneListBox.Value)), ...
                    1);
                app.vThresholdSpinner.Value = app.pvxArr( ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                    app.geneMap(string(app.GeneListBox.Value)), ...
                    2);
                app.xThresholdSpinner.Value = app.pvxArr( ...
                    app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                    app.geneMap(string(app.GeneListBox.Value)), ...
                    3);
            end
            
            fprintf ("APP: Selected nucle[i] %s\n", string(value));
            redrawXNTGAxes (app);
        end

        % Callback function
        function pSmoothingSliderValueChanged(app, event)
            value = app.pSmoothingSlider.Value;
            % Store changed p value to pvxArr array.
            
            
            % YL 2020-6-2: The issue with the code below is that
            % the user may have selected MULTIPLE GENES and also MULTIPLE
            % NUCLEI.
            % I think geneMap(...) does NOT handle the case of multiple
            % genes.
            %
            % Anyway, if we are making a GUI for actual distribution,
            % we need to figure out how we want multliselection to work
            % when sliders are changed, as we discussed
            
            
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                    app.geneMap(string(app.GeneListBox.Value)), 1) = value;
            end
            disp(app.pvxArr);
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.optionsFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedOpt(n,g,1) = 1;
                end              
            end
            redrawXNTGAxes (app);
        end

        % Callback function
        function vThresholdSliderValueChanged(app, event)
            value = app.vThresholdSlider.Value;
            % Store changed v value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                    app.geneMap(string(app.GeneListBox.Value)), 2) = value;
            end
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.optionsFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedOpt(n,g,2) = 1;
                end              
            end
            redrawXNTGAxes (app);
        end

        % Callback function
        function xThresholdSliderValueChanged(app, event)
            value = app.xThresholdSlider.Value;
            % Store changed x value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                    app.geneMap(string(app.GeneListBox.Value)), 3) = value;
            end
            
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.optionsFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedOpt(n,g,3) = 1;
                end              
            end
            
            redrawXNTGAxes (app);
        end

        % Button pushed function: SaveoptionsButton
        function SaveoptionsButtonPushed(app, event)
           
            [file, path] = uiputfile('*'); %open file
            filename = strcat(path,file);
            saveMDA (filename, app.pvxArr);  % YLL 2020-6-2.  This exists, no need to reinvent wheel
            
            %fmtstr = '%.4f';    % TO DO: supply this to saveMDA as an
            %option
        end

        % Button pushed function: LoadoptionsButton
        function LoadoptionsButtonPushed(app, event)
            
            % Handle situation when user clicks Load Options button without 
            % choosing the options data file 
            if strcmp(app.OptionsfileTextArea.Value, 'File name...')
                
                msgbox({'Must choose a file containing options values', ...
                        'before loading'}, 'Error', 'error');
                return;
            
            end
            
            % UGLY HACK FOR MAC
            %app.FIGRParameterExplorerUIFigure.Visible = 'off';  
            %[file, path] = uigetfile('*'); % Open file.
            %app.FIGRParameterExplorerUIFigure.Visible = 'on';  % UGLY HACK
            %filename = strcat(path,file);          
            app.pvxUser = loadMDA (app.OptionsfileTextArea.Value{1});    % YLL 2020-6-2.  This exists, no need to reinvent wheel

            app.optionsFileLoaded = 1;
            
            % Initialize array for checking whether a user had changed 
            % the parameters p, v, and x after loading the options file.
            
            % YLL 2020-6-2:  Should discuss with JH: 
            % Do we really need to care whether user has loaded param file
            % or not?
            
            % YLL 2020-6-2:  Straightened out indices below
            
            
            [numNuclei] = numel (app.nucleusNames);
            [numGenes] = numel (app.geneNames);           
            for n=1:numNuclei
                for g=1:numGenes
                    app.userChangedOpt(n,g,1) = 0;
                    app.userChangedOpt(n,g,2) = 0;
                    app.userChangedOpt(n,g,3) = 0;
                end
            end
            
            % Manu 07/20/2020: Fixed bug below where the third index of
            % pvxUser was 1 in all three lines. Also, why are we looping
            % unnecessarily?
            app.pvxArr = app.pvxUser;
  
            % Update current slider values.
            % Manu 07/20/2020: This was being done inside the loop, which
            % meant that the current slider value was being set to that of
            % the last gene and last nucleus/condition instead of the
            % currently selected one. Let us also reset which gene/nuclei
            % are selected since presumably the user is starting fresh or
            % starting over.
            app.GeneListBox.Value = app.GeneListBox.Items{1};
            app.NucleusListBox.Value = app.NucleusListBox.Items{1};
            
            app.pSmoothingSpinner.Value = app.pvxUser(1,1,1);
            app.vThresholdSpinner.Value = app.pvxUser(1,1,2);
            app.xThresholdSpinner.Value = app.pvxUser(1,1,3);
            
            redrawXNTGAxes (app);
        end

        % Callback function
        function tSafetySliderValueChanged(app, event)
            value = app.tSafetySlider.Value;
            % Force cursor to land on discrete values.
            % Determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % Move the slider to that option.
            event.Source.Value = event.Source.MajorTicks(minIdx);            
            value = event.Source.MajorTicks(minIdx);
            redrawXNTGAxes (app);
        end

        % Callback function
        function vThresholdQuestionButtonPushed(app, event)
            msgbox({'vThreshold controls the derivative threshold for the ON/OFF state of the gene at time t.'});      
        end

        % Callback function
        function xThresholdQuestionButtonPushed(app, event)
            msgbox({'xThreshold controls the expression threshold for the ON/OFF state of the gene if its derivative at time t is greater than vThreshold.'});
        end

        % Callback function
        function pSmoothingQuestionButtonPushed(app, event)
            msgbox({'pSmoothing controls the smoothing parameter of the cubic spline.' });
        end

        % Callback function
        function tSafetyQuestionButtonPushed(app, event)
            msgbox({'tSafety param filters out data points when estimating options R and lambda. Filtered data points have slopes are not part of a run of its value. This param is not stored in output but provides '});
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            
            % Handle situation when user clicks Load Files button before choosing 
            % the expression data file 
            if strcmp(app.GeneexpressiondatafileTextArea.Value, 'File name...')
                
                msgbox({'Must choose a file containing expression data', ...
                        'before loading'}, 'Error', 'error');
                return;
            
            end
            
            % Handle situation when user clicks Load Files button before choosing 
            % the time point file 
            if strcmp(app.TimepointsfileTextArea.Value, 'File name...')
                
                msgbox({'Must choose a file containing time points', ...
                        'before loading'}, 'Error', 'error');
                return;
            
            end
            
            % If everything OK, then go ahead and load the files
            
            % Let's check which file format we are working with and call
            % the appropriate function
            if (app.MDA.Value)
                
                loadMDADataTimepoints(app, ...
                             app.GeneexpressiondatafileTextArea.Value{1},...
                             app.TimepointsfileTextArea.Value{1} ...
                            );
                        
            elseif (app.NCBI.Value)
                
                loadNCBIDataTimepoints(app, ...
                              app.GeneexpressiondatafileTextArea.Value{1},...
                              app.TimepointsfileTextArea.Value{1} ...
                             );
                             
            end
        end

        % Button pushed function: InferGRNButton
        function InferGRNButtonPushed(app, event)
            
            %---------- need to supply numGenes somehow
            %numGenes = size (app.xntg, 3);
            numGenes = inputdlg ('How many genes to recompute?','Enter numGenes');
            numGenes = str2num (numGenes{1});
            disp (numGenes);
            
            %=============== CALL infer
            opts = struct(  'debug', 0, ...       
                'geneNames', app.geneNames, ...
                'slopethresh', NaN, ...         
                'exprthresh', NaN, ...
                'splinesmoothing', NaN, ...
                'spatialsmoothing', 0.5, ...
                'minborder_expr_ratio', 0.01, ...
                'Rld_method', 'slope', ...
                'Rld_tsafety', 3, ...       % should eventually ged rid
                'synthesisfunction', 'synthesis_sigmoid_sqrt', ...
                'ODEAbsTol', 1e-3, ...
                'ODEsolver', 'ode45', ...
                'pvxOpts_ngo', app.pvxArr, ...
                'lambda', 0.5, ...
                'lm', 'FIGRlogReg'); % glmfit, FIGRlogReg, lassoglm     
            %fprintf ("going to call infer with pvx Options set to ");
            %disp (opts.pvxOpts_ngo);            
            [app.grn, diagnostics] = infer (opts, app.xntg, app.tt, numGenes);            
            app.yntg = diagnostics.yntg;
            
            %=============== PRINT T MATRIX
            disp ("T matrix:");
            disp (app.grn.Tgg);
            %filename = 'FIGR_Tmatrix.txt';
            %saveTMatrix (filename, grnFIGR.Tgg, geneNames);

            %=============== CALL computeTRajs
            [app.xntgRECAL] = computeTrajs (opts, app.grn, app.xntg, app.tt);
            
            fprintf ("computeTrajs: size(app.xntgRECAL) ");
            disp (size(app.xntgRECAL));
            fprintf ("\n");
            
            redrawXNTGAxes (app);
            redrawBiplotAxes (app);
        end

        % Callback function
        function SaveexpressiondataButtonPushed(app, event)
            [file, path] = uiputfile('*'); %open file
            filename = strcat(path,file);
            saveMDA (filename, app.pvxArr);  % YLL 2020-6-2.  This exists, no need to reinvent wheel            
        end

        % Button pushed function: ChooseButton_expression_file
        function ChooseButton_expression_filePushed(app, event)

            %==========================================================================================
            %  Open file chooser dialog and populate the text area with the
            %  name of the file
            %==========================================================================================

            [file, path] = uigetfile('*');   % YLL 2020-6-1.  Launch "Open File" dialog.
            app.GeneexpressiondatafileTextArea.Value = strcat (path,file);  %"fly_xntg.mda.txt";

        end

        % Button pushed function: ChooseButton_timepoint_file
        function ChooseButton_timepoint_filePushed(app, event)
            
            %==========================================================================================
            %  Open file chooser dialog and populate the text area with the
            %  name of the file
            %==========================================================================================
            
            [file, path] = uigetfile('*');   
            app.TimepointsfileTextArea.Value = strcat (path,file);  
            
        end

        % Button pushed function: ChooseButton_options_file
        function ChooseButton_options_filePushed(app, event)
             
            %==========================================================================================
            %  Open file chooser dialog and populate the text area with the
            %  name of the file
            %==========================================================================================
            
            [file, path] = uigetfile('*');   
            app.OptionsfileTextArea.Value = strcat (path,file);  
            
        end

        % Value changed function: pSmoothingSpinner
        function pSmoothingSpinnerValueChanged(app, event)
            value = app.pSmoothingSpinner.Value;
            disp(value);
            % Store changed p value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                    app.geneMap(string(app.GeneListBox.Value)), 1) = value;
            end
            disp(app.pvxArr);
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.optionsFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedOpt(n,g,1) = 1;
                end              
            end
            redrawXNTGAxes (app);
        end

        % Value changed function: vThresholdSpinner
        function vThresholdSpinnerValueChanged(app, event)
            value = app.vThresholdSpinner.Value;
             % Store changed v value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                    app.geneMap(string(app.GeneListBox.Value)), 2) = value;
            end
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.optionsFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedOpt(n,g,2) = 1;
                end              
            end
            redrawXNTGAxes (app);
        end

        % Value changed function: xThresholdSpinner
        function xThresholdSpinnerValueChanged(app, event)
            value = app.xThresholdSpinner.Value;
            % Store changed x value to pvxArr array.
            for i=1:numel(string(app.NucleusListBox.Value))
                app.pvxArr(app.nucleusMap(string(app.NucleusListBox.Value(i))), ...
                    app.geneMap(string(app.GeneListBox.Value)), 3) = value;
            end
            
            % Set flag to 1 for changed param after file with parameter was loaded.
            if(app.optionsFileLoaded)                          
                g = app.geneMap(string(app.GeneListBox.Value));             
                for i=1:numel(string(app.NucleusListBox.Value))
                    n = app.nucleusMap(string(app.NucleusListBox.Value(i)));
                    app.userChangedOpt(n,g,3) = 1;
                end              
            end
            
            redrawXNTGAxes (app);
        end

        % Value changed function: tSafetySpinner
        function tSafetySpinnerValueChanged(app, event)
            value = app.tSafetySpinner.Value;
            redrawXNTGAxes (app);
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
                      
            % Save current values of the parameters
            currpSmoothing = app.pSmoothingSpinner.Value;
            currvThreshold = app.vThresholdSpinner.Value;
            currxThreshold = app.xThresholdSpinner.Value;
            currtSafety = app.tSafetySpinner.Value;
            
            % Where should we copy
            
            % Unless specified otherwise, we have to copy to current
            % nucleus/condition (if only the "Copy to all other genes" is
            % checked) or to the current gene (if only the "Copy to all
            % other nuclei/conditions" is checked)
            targetGeneIndices = cell2mat(values(...
                                app.geneMap, ...
                                cellstr(app.GeneListBox.Value)));
                            
            targetNucleusIndices = cell2mat(values(...
                                app.nucleusMap, ...
                                cellstr(app.NucleusListBox.Value)));
            
            % If the "Copy to all other nuclei/conditions" checkbox is
            % checked then the target should be all the nuclei/conditions
            if (app.CopytoallnucleiconditionsCheckBox.Value)
                
                [numNuclei] = numel(app.nucleusNames);
                targetNucleusIndices = [1:numNuclei]; 
            
            end
            
            % If the "Copy to all other genes" checkbox is
            % checked then the target should be all the nuclei/conditions
            if (app.CopytoallothergenesCheckBox.Value)
                
                [numGenes] = numel(app.geneNames);
                targetGeneIndices = [1:numGenes]; 
            
            end
            
            % If we have to copy pSmoothing value, do so
            if (app.CopypSmoothing.Value)
                app.pvxArr(targetNucleusIndices, targetGeneIndices, 1) ...
                    = currpSmoothing;
            end
            
            % If we have to copy vThreshold value, do so
            if (app.CopyvThreshold.Value)
                app.pvxArr(targetNucleusIndices, targetGeneIndices, 2) ...
                    = currvThreshold;
            end
            
            % If we have to copy xThreshold value, do so
            if (app.CopyxThreshold.Value)
                app.pvxArr(targetNucleusIndices, targetGeneIndices, 3) ...
                    = currxThreshold;
            end
            
            % If we have to copy tSafety value, do nothing
            if (app.CopytSafety.Value)
                
            end
            
            % Once done copying, reset all check boxes to unchecked
            app.CopypSmoothing.Value = 0;
            app.CopyvThreshold.Value = 0;
            app.CopyxThreshold.Value = 0;
            app.CopytSafety.Value = 0;
            app.CopytoallothergenesCheckBox.Value = 0;
            app.CopytoallnucleiconditionsCheckBox.Value = 0;
            
            % Disable the copy button
            app.enabledisableCopyButton(app);
            
        end

        % Value changed function: CopypSmoothing, CopytSafety, 
        % CopytoallnucleiconditionsCheckBox, 
        % CopytoallothergenesCheckBox, CopyvThreshold, 
        % CopyxThreshold
        function enabledisableCopyButton(app, event)
            % Check whether at least one of the parameter copying
            % checkboxes and one of the "Copy to..." buttons are checked.
            % If the condition is met enable the Copy button. If the
            % condition is not met, disable it.
            
            enableCopyButton = (app.CopypSmoothing.Value ...
                                | app.CopyvThreshold.Value ...
                                | app.CopyxThreshold.Value ...
                                | app.CopytSafety.Value) & ...
                                (app.CopytoallothergenesCheckBox.Value ...
                                | app.CopytoallnucleiconditionsCheckBox.Value);
                            
            if enableCopyButton
                set(app.CopyButton, 'Enable', 'on');
            else
                set(app.CopyButton, 'Enable', 'off');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIGRParameterExplorerUIFigure = uifigure('Visible', 'off');
            app.FIGRParameterExplorerUIFigure.Name = 'FIGR Parameter Explorer';
            app.xntgAxes = uiaxes(app.FIGRParameterExplorerUIFigure);
            title(app.xntgAxes, 'Gene expression trajectory')
            xlabel(app.xntgAxes, 'Time t')
            ylabel(app.xntgAxes, 'Expression x')
            app.GeneListBoxLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.GeneListBoxLabel.Text = 'Gene';
            app.GeneListBox = uilistbox(app.FIGRParameterExplorerUIFigure);
            app.GeneListBox.ValueChangedFcn = createCallbackFcn(app, @GeneListBoxValueChanged, true);
            app.NucleusListBoxLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.NucleusListBoxLabel.Text = 'Nucleus';
            app.NucleusListBox = uilistbox(app.FIGRParameterExplorerUIFigure);
            app.NucleusListBox.Items = {'8226', '8227'};
            app.NucleusListBox.Multiselect = 'on';
            app.NucleusListBox.ValueChangedFcn = createCallbackFcn(app, @NucleusListBoxValueChanged, true);
            app.NucleusListBox.Value = {'8226', '8227'};
            app.SaveoptionsButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.SaveoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoptionsButtonPushed, true);
            app.SaveoptionsButton.Text = 'Save options...';
            app.LoadoptionsButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.LoadoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadoptionsButtonPushed, true);
            app.LoadoptionsButton.Text = 'Load options';
            app.LoadFilesButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.InferGRNButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.InferGRNButton.ButtonPushedFcn = createCallbackFcn(app, @InferGRNButtonPushed, true);
            app.InferGRNButton.Text = 'Infer GRN';
            app.MouseoveraspinnertoviewitstooltipLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.MouseoveraspinnertoviewitstooltipLabel.Text = 'Mouseover a spinner to view its tooltip!';
            app.biplotAxes = uiaxes(app.FIGRParameterExplorerUIFigure);
            title(app.biplotAxes, 'Biplot')
            xlabel(app.biplotAxes, 'Gene g_i')
            ylabel(app.biplotAxes, 'Gene g_j')
            app.ChooseFileFormat = uibuttongroup(app.FIGRParameterExplorerUIFigure);
            app.ChooseFileFormat.Title = 'File Format';
            app.MDA = uiradiobutton(app.ChooseFileFormat);
            app.MDA.Text = 'MDA';
            app.NCBI = uiradiobutton(app.ChooseFileFormat);
            app.NCBI.Text = 'NCBI';
            app.NCBI.Value = true;
            app.GeneexpressiondatafileTextAreaLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.GeneexpressiondatafileTextAreaLabel.Text = 'Gene expression data file';
            app.GeneexpressiondatafileTextArea = uitextarea(app.FIGRParameterExplorerUIFigure);
            app.GeneexpressiondatafileTextArea.Value = {'eryneu_xntg.txt'};
            app.TimepointsfileTextAreaLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.TimepointsfileTextAreaLabel.Text = 'Time points file';
            app.TimepointsfileTextArea = uitextarea(app.FIGRParameterExplorerUIFigure);
            app.TimepointsfileTextArea.Value = {'eryneu_tt.txt'};
            app.ChooseButton_expression_file = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.ChooseButton_expression_file.ButtonPushedFcn = createCallbackFcn(app, @ChooseButton_expression_filePushed, true);
            app.ChooseButton_expression_file.Text = 'Choose...';
            app.ChooseButton_timepoint_file = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.ChooseButton_timepoint_file.ButtonPushedFcn = createCallbackFcn(app, @ChooseButton_timepoint_filePushed, true);
            app.ChooseButton_timepoint_file.Text = 'Choose...';
            app.OptionsfileTextAreaLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.OptionsfileTextAreaLabel.Text = 'Options file';
            app.OptionsfileTextArea = uitextarea(app.FIGRParameterExplorerUIFigure);
            app.ChooseButton_options_file = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.ChooseButton_options_file.ButtonPushedFcn = createCallbackFcn(app, @ChooseButton_options_filePushed, true);
            app.ChooseButton_options_file.Text = 'Choose...';
            app.pSmoothingSpinnerLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.pSmoothingSpinnerLabel.Text = 'pSmoothing';
            app.pSmoothingSpinner = uispinner(app.FIGRParameterExplorerUIFigure);
            app.pSmoothingSpinner.Step = 0.01;
            app.pSmoothingSpinner.Limits = [0 1];
            app.pSmoothingSpinner.ValueDisplayFormat = '%1.4g';
            app.pSmoothingSpinner.ValueChangedFcn = createCallbackFcn(app, @pSmoothingSpinnerValueChanged, true);
            app.pSmoothingSpinner.Tooltip = {'pSmoothing controls the smoothing parameter of the cubic spline.'};
            app.pSmoothingSpinner.Value = 0.7;
            app.vThresholdSpinnerLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.vThresholdSpinnerLabel.Text = 'vThreshold';
            app.vThresholdSpinner = uispinner(app.FIGRParameterExplorerUIFigure);
            app.vThresholdSpinner.Step = 0.0002;
            app.vThresholdSpinner.Limits = [0 Inf];
            app.vThresholdSpinner.ValueDisplayFormat = '%11.6g';
            app.vThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @vThresholdSpinnerValueChanged, true);
            app.vThresholdSpinner.Tooltip = {'vThreshold controls the derivative threshold for the ON/OFF state of the gene at time t.'};
            app.vThresholdSpinner.Value = 0.01;
            app.xThresholdSpinnerLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.xThresholdSpinnerLabel.Text = 'xThreshold';
            app.xThresholdSpinner = uispinner(app.FIGRParameterExplorerUIFigure);
            app.xThresholdSpinner.Step = 0.01;
            app.xThresholdSpinner.Limits = [0 Inf];
            app.xThresholdSpinner.ValueDisplayFormat = '%11.6g';
            app.xThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @xThresholdSpinnerValueChanged, true);
            app.xThresholdSpinner.Tooltip = {'xThreshold controls the expression threshold for the ON/OFF state of the gene if its derivative at time t is greater than vThreshold.'};
            app.xThresholdSpinner.Value = 0.5;
            app.tSafetySpinnerLabel = uilabel(app.FIGRParameterExplorerUIFigure);
            app.tSafetySpinnerLabel.Text = 'tSafety';
            app.tSafetySpinner = uispinner(app.FIGRParameterExplorerUIFigure);
            app.tSafetySpinner.Limits = [0 5];
            app.tSafetySpinner.RoundFractionalValues = 'on';
            app.tSafetySpinner.ValueDisplayFormat = '%1.0g';
            app.tSafetySpinner.ValueChangedFcn = createCallbackFcn(app, @tSafetySpinnerValueChanged, true);
            app.tSafetySpinner.Tooltip = {'tSafety param filters out data points when estimating options R and lambda. Filtered data points have slopes are not part of a run of its value. This param is not stored in output.'};
            app.CopypSmoothing = uicheckbox(app.FIGRParameterExplorerUIFigure);
            app.CopypSmoothing.ValueChangedFcn = createCallbackFcn(app, @enabledisableCopyButton, true);
            app.CopypSmoothing.Tooltip = {'Select the pSmoothing value of the current gene/nucleus to be copied to other genes or nuclei.'};
            app.CopypSmoothing.Text = 'Copy';
            app.CopyvThreshold = uicheckbox(app.FIGRParameterExplorerUIFigure);
            app.CopyvThreshold.ValueChangedFcn = createCallbackFcn(app, @enabledisableCopyButton, true);
            app.CopyvThreshold.Tooltip = {'Select the vThreshold value of the current gene/nucleus to be copied to other genes or nuclei.'};
            app.CopyvThreshold.Text = 'Copy';
            app.CopyxThreshold = uicheckbox(app.FIGRParameterExplorerUIFigure);
            app.CopyxThreshold.ValueChangedFcn = createCallbackFcn(app, @enabledisableCopyButton, true);
            app.CopyxThreshold.Tooltip = {'Select the xThreshold value of the current gene/nucleus to be copied to other genes or nuclei.'};
            app.CopyxThreshold.Text = 'Copy';
            app.CopytSafety = uicheckbox(app.FIGRParameterExplorerUIFigure);
            app.CopytSafety.ValueChangedFcn = createCallbackFcn(app, @enabledisableCopyButton, true);
            app.CopytSafety.Tooltip = {'Select the tSafety value of the current gene/nucleus to be copied to other genes or nuclei.'};
            app.CopytSafety.Text = 'Copy';
            app.CopytoallothergenesCheckBox = uicheckbox(app.FIGRParameterExplorerUIFigure);
            app.CopytoallothergenesCheckBox.ValueChangedFcn = createCallbackFcn(app, @enabledisableCopyButton, true);
            app.CopytoallothergenesCheckBox.Tooltip = {'Check if you''d like to copy the parameters of the currently selected gene to the currently selected gene to the currently selected nucleus of all other genes. If "Copy to all nuclei/conditions" is also checked then parameters will be copied to all gene/nuclei combinations.'};
            app.CopytoallothergenesCheckBox.Text = 'Copy to all other genes';
            app.CopytoallnucleiconditionsCheckBox = uicheckbox(app.FIGRParameterExplorerUIFigure);
            app.CopytoallnucleiconditionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @enabledisableCopyButton, true);
            app.CopytoallnucleiconditionsCheckBox.Tooltip = {'Check if you''d like to copy parameters from the currently selected nucleus to all the nuclei of the selected gene. If "Copy to all other genes" is also checked then parameters will be copied to all gene/nuclei combinations.'};
            app.CopytoallnucleiconditionsCheckBox.Text = 'Copy to all nuclei/conditions';
            app.CopyButton = uibutton(app.FIGRParameterExplorerUIFigure, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Enable = 'off';
            app.CopyButton.Text = 'Copy';
            % Show the figure after all components are created
            app.FIGRParameterExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figrApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIGRParameterExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIGRParameterExplorerUIFigure)
        end
    end
end